classdef DoAnPPT_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        TimNghiem                  matlab.ui.container.Tab
        SlnlpLabel                 matlab.ui.control.Label
        KtqunghimLabel             matlab.ui.control.Label
        ChnphngphptnhLabel         matlab.ui.control.Label
        NhpkhongsaisLabel          matlab.ui.control.Label
        bLabel                     matlab.ui.control.Label
        aLabel                     matlab.ui.control.Label
        SoLanLap                   matlab.ui.control.NumericEditField
        KetQuaNghiem               matlab.ui.control.NumericEditField
        NhapSaiSo                  matlab.ui.control.NumericEditField
        bEditField                 matlab.ui.control.NumericEditField
        aEditField                 matlab.ui.control.NumericEditField
        KhongphnlinghimLabel       matlab.ui.control.Label
        VeButton                   matlab.ui.control.Button
        ChonPPT                    matlab.ui.control.DropDown
        NhapPT                     matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel  matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        Tab2                       matlab.ui.container.Tab
        Tab3                       matlab.ui.container.Tab
        Tab4                       matlab.ui.container.Tab
        Tab5                       matlab.ui.container.Tab
        Tab6                       matlab.ui.container.Tab
    end

    
    methods (Access = public)
        
        function [c, n] = chiadoi(app,fx, a ,b ,saiso)
n = 0;
fxi = str2func(['@(x)', fx]);
while true 
c = (a + b)/2;
if(fxi(a)*fxi(c) < 0)
    b = c;
else 
    a = c;
end
n = n + 1;
if abs(b-a) < saiso
    break;
end
end
end

    function[ x1, n]= lap(app,fx, a, saiso) 
    n = 0;  
    lambda = 1;   % có thể chỉnh nếu không hội tụ

    % fx là chuỗi → đổi thành function
    f = str2func(['@(x)', fx]);

    % hàm lặp
    fp = @(x) x - lambda * f(x);
    x0 = a; 
    for i = 1:2000 
    x1 = fp(x0); 
    n = n+1; 
    if abs(x1 - x0) < saiso 
    break; 
    end 
    x0 = x1; 
    end 
    end

    function [x1, n] = Newton(app,fx, a, b, saiso) 
    syms x; 
    f = str2func(['@(x)', fx]);        
    df = matlabFunction(diff(str2sym(fx), x));   
    f2 = matlabFunction(diff(diff(str2sym(fx), x)));  
    if f(a) * f2(a) > 0 
    x0 = a; 
    else 
    x0 = b; 
    end 
    n = 0; 
    while true 
    n = n + 1; 
    x1 = x0 - f(x0) / df(x0);   
    if abs(x1 - x0) < saiso 
    break; 
    end 
    x0 = x1; 
    if df(x0) == 0 
    warning('Đạo hàm bằng 0 tại x = %.6f, phương pháp dừng.', x0); 
    break; 
    end 
    end 
    end 
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VeButton
        function VeButtonPushed(app, event)
            fx = app.NhapPT.Value;
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            saiso = app.NhapSaiSo.Value;
            pp = app.ChonPPT.Value;

            switch pp
        case 'Chia đôi'
            [nghiem, solanlap] = app.chiadoi(fx,a,b,saiso);

        case 'Lặp'
            [nghiem, solanlap] = app.lap(fx,a,saiso);

         case'Newton'
            [nghiem, solanlap] = app.Newton(fx,a,b,saiso);
    end

    % Vẽ đồ thị hội tụ
               % Vẽ đồ thị hội tụ
    plot(app.UIAxes, solanlap, nghiem,'-o');
    xlabel(app.UIAxes,'Số lần lặp');
    ylabel(app.UIAxes,'Giá trị nghiệm');
    grid(app.UIAxes,'on');

    % Hiển thị kết quả cuối
    app.KetQuaNghiem.Value = nghiem(end);
    app.SoLanLap.Value = solanlap(end);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TimNghiem = uitab(app.TabGroup);
            app.TimNghiem.Title = 'Tìm Nghiệm';
            app.UIAxes = uiaxes(app.TimNghiem);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NhpphngtrnhEditFieldLabel = uilabel(app.TimNghiem);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.NhapPT = uieditfield(app.TimNghiem, 'text');
            app.NhapPT.Value = 'x - sin(x) -0.25';
            app.ChonPPT = uidropdown(app.TimNghiem);
            app.ChonPPT.Items = {'Chia đôi', 'Lặp', 'Newton'};
            app.ChonPPT.Value = 'Chia đôi';
            app.VeButton = uibutton(app.TimNghiem, 'push');
            app.VeButton.ButtonPushedFcn = createCallbackFcn(app, @VeButtonPushed, true);
            app.VeButton.Text = 'Vẽ';
            app.KhongphnlinghimLabel = uilabel(app.TimNghiem);
            app.KhongphnlinghimLabel.Text = 'Khoảng phân li nghiệm';
            app.aEditField = uieditfield(app.TimNghiem, 'numeric');
            app.aEditField.Value = -1;
            app.bEditField = uieditfield(app.TimNghiem, 'numeric');
            app.bEditField.Value = 2;
            app.NhapSaiSo = uieditfield(app.TimNghiem, 'numeric');
            app.NhapSaiSo.Value = 0.005;
            app.KetQuaNghiem = uieditfield(app.TimNghiem, 'numeric');
            app.SoLanLap = uieditfield(app.TimNghiem, 'numeric');
            app.aLabel = uilabel(app.TimNghiem);
            app.aLabel.Text = 'a';
            app.bLabel = uilabel(app.TimNghiem);
            app.bLabel.Text = 'b';
            app.NhpkhongsaisLabel = uilabel(app.TimNghiem);
            app.NhpkhongsaisLabel.Text = 'Nhập khoảng sai số';
            app.ChnphngphptnhLabel = uilabel(app.TimNghiem);
            app.ChnphngphptnhLabel.Text = 'Chọn phương pháp tính';
            app.KtqunghimLabel = uilabel(app.TimNghiem);
            app.KtqunghimLabel.Text = 'Kết quả nghiệm';
            app.SlnlpLabel = uilabel(app.TimNghiem);
            app.SlnlpLabel.Text = 'Số lần lặp';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Title = 'Tab5';
            app.Tab6 = uitab(app.TabGroup);
            app.Tab6.Title = 'Tab6';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DoAnPPT_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end